classdef audiogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BadanieAudiogramemUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        JestemwiadomycelubadaniaibdpostpowazgodniezinstrukcjamiCheckBox  matlab.ui.control.CheckBox
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        BadanieAudiogramemLabel     matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        Image2                      matlab.ui.control.Image
        IlemaszlatDropDown          matlab.ui.control.DropDown
        IlemaszlatDropDownLabel     matlab.ui.control.Label
        PowiedznamtrochosobieLabel  matlab.ui.control.Label
        JakoceniaszswjsuchDropDown  matlab.ui.control.DropDown
        JakoceniaszswjsuchDropDownLabel  matlab.ui.control.Label
        DalejButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DalejButton
        function DalejButtonPushed(app, event)
          if app.JestemwiadomycelubadaniaibdpostpowazgodniezinstrukcjamiCheckBox.Value == 0 || app.JakoceniaszswjsuchDropDown.Value == "Jestem głuchy" || app.IlemaszlatDropDown.Value == "0-12" || app.IlemaszlatDropDown.Value == "80+"
              closereq;
              error;
          else
              uiwait(msgbox('Zanim zaczniemy chcielibysmy dokonać testu głośności twojego zestawu słuchawkowego.'));
              %Testowanie dźwięku
              Freq_test = 1000;
              T=[0:1/Freq_test:2];
              y = sin(2*pi*200*T);
              sound(y, Freq_test);
              pause(2);
              answer = questdlg('Czy słyszysz dźwięk?','Test dźwięku','Tak','Nie','Tak')
              while answer == 'Nie'
                  uiwait(msgbox('Zwiększ głośność systemu'));
                  pause(2);
                  if answer == 'Nie'
                      Freq_test = 1000;
                      T=[0:1/Freq_test:2];
                      y = sin(2*pi*200*T);
                      sound(y, Freq_test);
                      pause(1);
                  end
                  answer = questdlg('Czy słyszysz już dźwięk?','Test dźwięku','Tak','Nie','Tak')
              end
              closereq;
              badanie2;
          end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BadanieAudiogramemUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {321, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BadanieAudiogramemUIFigure = uifigure('Visible', 'off');
            app.BadanieAudiogramemUIFigure.Name = 'Badanie Audiogramem';
            app.BadanieAudiogramemUIFigure.Icon = 'headphone icon vector.png';
            app.BadanieAudiogramemUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.BadanieAudiogramemUIFigure.Tag = 'audiogram';
            app.GridLayout = uigridlayout(app.BadanieAudiogramemUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BadanieAudiogramemLabel = uilabel(app.LeftPanel);
            app.BadanieAudiogramemLabel.Text = 'Badanie Audiogramem';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'headphone icon vector.png';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'W badaniu będą Ci potrzebne słuchawki stereo ze sprawnymi obydwoma kanałami. Ustaw głośność na 50% przed przystąpieniem do badania'; ''};
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'Witaj w aplikacji audiogramu! Pamiętaj, że program nie jest zabawką i nie powinien być wykorzystywany do rozrywki, gdyż częsty odsłuch może trwale uszkodzić Twój słuch. Jego działanie nie zastąpi profesjonalnej diagnozy lekarza, lecz może dostarczyć pewnych przydatnych informacji na temat stanu zdrowia Twojego zmysłu słuchu.';
            app.JestemwiadomycelubadaniaibdpostpowazgodniezinstrukcjamiCheckBox = uicheckbox(app.LeftPanel);
            app.JestemwiadomycelubadaniaibdpostpowazgodniezinstrukcjamiCheckBox.Text = 'Jestem świadomy celu badania i będę postępował zgodnie z instrukcjami';
            app.RightPanel = uipanel(app.GridLayout);
            app.DalejButton = uibutton(app.RightPanel, 'push');
            app.DalejButton.ButtonPushedFcn = createCallbackFcn(app, @DalejButtonPushed, true);
            app.DalejButton.Text = 'Dalej ->';
            app.JakoceniaszswjsuchDropDownLabel = uilabel(app.RightPanel);
            app.JakoceniaszswjsuchDropDownLabel.Text = 'Jak oceniasz swój słuch?';
            app.JakoceniaszswjsuchDropDown = uidropdown(app.RightPanel);
            app.JakoceniaszswjsuchDropDown.Items = {'Idealny', 'Dobry', 'Średni', 'Słaby', 'Jestem głuchy'};
            app.JakoceniaszswjsuchDropDown.Value = 'Idealny';
            app.PowiedznamtrochosobieLabel = uilabel(app.RightPanel);
            app.PowiedznamtrochosobieLabel.Text = 'Powiedz nam trochę o sobie';
            app.IlemaszlatDropDownLabel = uilabel(app.RightPanel);
            app.IlemaszlatDropDownLabel.Text = 'Ile masz lat?';
            app.IlemaszlatDropDown = uidropdown(app.RightPanel);
            app.IlemaszlatDropDown.Items = {'0-12', '13-18', '19-29', '30-39', '40-49', '50-59', '60-69', '70-79', '80+', '', '', ' '};
            app.IlemaszlatDropDown.Value = '0-12';
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = 'wave.png';
            % Show the figure after all components are created
            app.BadanieAudiogramemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audiogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BadanieAudiogramemUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BadanieAudiogramemUIFigure)
        end
    end
end