classdef badanie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TakButton                matlab.ui.control.Button
        NieButton                matlab.ui.control.Button
        CzysyszyszjakidwikLabel  matlab.ui.control.Label
        FeedbackTextAreaLabel    matlab.ui.control.Label
        FeedbackTextArea         matlab.ui.control.TextArea
    end

       properties (Access = public)
         dbpoziom=10;
         dbtab=[0,0,0,0,0,0,0,0,0];
         % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %uiwait(msgbox('zaraz rozpocznie się test','Uwaga!'));
        end

        % Button pushed function: TakButton
        function save(app, event)
         x=1;
       app.dbtab(x)=app.dbpoziom;
         x=x+1;
         if x>=9 
                 assignin("base",app.dbtab);
                 app.FeedbackTextArea.Value = app.dbpoziom;
                 x=1;
                 app.dbpoziom=10;


         else
             
         end
        end

        % Button pushed function: NieButton
        function glosniej(app, event)
          app.dbpoziom=app.dbpoziom+10;  
          app.FeedbackTextArea.Value = app.dbpoziom;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TakButton = uibutton(app.UIFigure, 'push');
            app.TakButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.TakButton.Text = 'Tak';
            app.NieButton = uibutton(app.UIFigure, 'push');
            app.NieButton.ButtonPushedFcn = createCallbackFcn(app, @glosniej, true);
            app.NieButton.Text = 'Nie';
            app.CzysyszyszjakidwikLabel = uilabel(app.UIFigure);
            app.CzysyszyszjakidwikLabel.Text = {'Czy słyszysz jakiś dźwięk?'; ''};
            app.FeedbackTextAreaLabel = uilabel(app.UIFigure);
            app.FeedbackTextAreaLabel.Text = 'Feedback';
            app.FeedbackTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = badanie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end